classdef controlTemperatura < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EscalonButton                  matlab.ui.control.Button
        TemperaturaGauge               matlab.ui.control.LinearGauge
        TemperaturaGaugeLabel          matlab.ui.control.Label
        StatusLabel_A                  matlab.ui.control.Label
        ConectarButton                 matlab.ui.control.Button
        BaudRateDropDown               matlab.ui.control.DropDown
        BaudRateDropDownLabel          matlab.ui.control.Label
        PORTDropDown                   matlab.ui.control.DropDown
        PORTDropDownLabel              matlab.ui.control.Label
        EstadodeconexionLamp           matlab.ui.control.Lamp
        EstadodeconexionLampLabel      matlab.ui.control.Label
        EstadodelcalefactorLamp        matlab.ui.control.Lamp
        EstadodelcalefactorLampLabel   matlab.ui.control.Label
        ActualizarButton               matlab.ui.control.Button
        IntervalodemuestreosEditField  matlab.ui.control.NumericEditField
        IntervalodemuestreosEditFieldLabel  matlab.ui.control.Label
        HisteresisCEditField           matlab.ui.control.NumericEditField
        HisteresisCEditFieldLabel      matlab.ui.control.Label
        SetpointCEditField             matlab.ui.control.NumericEditField
        SetpointCLabel                 matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        serialConection % Conexion de serie
        tempData        % guarda temperatura
        timeData        % guarda tiempo 
        
        setPoint
        histeresis
        intervalo

    end
    
    methods (Access = public)

        function lectura_DataSerial(app, src, ~)
            % Leer la línea de datos crudos del puerto serie
            crudo = readline(src); disp(crudo);       % Mostrar los datos crudos recibidos
            data = str2double(strsplit(crudo, ','));  % Separar los datos recibidos, asumiendo que están en formato CSV
            % Verificar si los datos son válidos
            if length(data) >= 2 && ~any(isnan(data))
                temperatura      = data(1); % Temperatura
                estadoCalefactor = data(2); % Estado del calefactor

                %% Actualizar el estado del calefactor
                if estadoCalefactor == 1
                    app.EstadodelcalefactorLamp.Color = [0.39,0.83,0.07]; % Verde
                else
                    app.EstadodelcalefactorLamp.Color = [0.78, 0.1, 0.1]; %rojo
                end

                %% Actualizar el valor de temperatura
                app.TemperaturaGauge.Value = temperatura;

                %% Veo si existen las variables, si no existen las crea
                if isempty(app.tempData) || isempty(app.timeData)
                    app.tempData    = [];
                    app.timeData    = [];
                    app.setPoint    = [];
                end

                %% Actualizar datos históricos
                app.tempData(end + 1) = temperatura;
                app.timeData(end + 1) = datetime('now');
                app.setPoint(end + 1) = app.setPoint;

                % Actualizar gráficos
                cla(app.UIAxes);
                plot(app.UIAxes, app.tempData.time, app.tempData.temp, "blue"); % Temperatura
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, app.tempData.time, app.tempData.spHistorico, "black--"); % Setpoint

                % Ajustar límites del eje
                app.UIAxes.XLim = [datetime('now') - minutes(0.5), datetime('now')]; % ultimos 30 segundos
                app.UIAxes.YLim = [0, 85];
                legend(app.UIAxes, {'DS1820', 'Setpoint'});

                % Dibujar el gráfico
                drawnow; %app.UIAxes
            else
                disp('Error: Datos malformados o incompletos.');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Funcion que se ejecuta cuando se inicializa la aplicacion
            app.PORTDropDown.Items = serialportlist("available");
            app.BaudRateDropDown.Items = {'1200', '2400','4800','9600','19200', '38400'};
            app.StatusLabel_A.Visible = "off";
            app.ActualizarButton.Enable ="on"; %probando, dsp apagar
            app.UIFigure.Resize = 'off';

            %Lee valores al inciar programa
            app.setPoint   = app.SetpointCEditField.Value;
            app.histeresis = app.HisteresisCEditField.Value;
            app.intervalo  = app.IntervalodemuestreosEditField.Value;
         
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            portChoice     = app.PORTDropDown;
            baudRateChoice = str2double(app.BaudRateDropDown);

            try
                app.serialConection = serialport(portChoice,baudRateChoice);
                configureTerminator(app.serialConection,"LF");
                %configureCallback(app.serialObj,"terminator",@(src, event) readSerialData(app,src,event));

                app.serialConection.BytesAvailableFcnMode = 'terminator';
                app.serialConection.BytesAvailableFcn = @(src, event) readSerialData(app, src, event);


                app.EstadodeconexionLamp.Color = 'green';
                app.ActualizarButton.Enable    = "on";
                app.EscalonButton.Enable       = "on";

                escalonButton(app, event);

            catch ME
                app.EstadodeconexionLamp.Color = [0.78, 0.1, 0.1]; %rojo
                disp("Error conectando a la placa");
                app.ActualizarButton.Enable = "off";
                app.EscalonButton.Enable    = "off";
            end
        
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            app.setPoint   = app.SetpointCEditField.Value;
            app.histeresis = app.HisteresisCEditField.Value;
            app.intervalo  = app.IntervalodemuestreosEditField.Value;

            %% Verifico que este conectado
            if isempty(app.serialConection) || ~isvalid(app.serialConection)
                return;
            end

            %% Construir el mensaje a enviar
            % Ejemplo de formato: "SET:SP=25.0,H=2.0,SI=1.0\n"
            message = sprintf("SET: SP = %d, H = %d, SI = %d\n", round(app.setPoint), ...
                                                                 round(app.histeresis), ...
                                                                 round(app.intervalo));
            try
                % Enviar el mensaje a través de la conexión serial
                write(app.serialConnection, message, "char");
                % mensaje = [char(0x02),mensaje,char(0x03)];
                fprintf(app.serialConection, message);

                % Actualizar el estado en la GUI
                app.StatusLabel_A.Visible = "on";
                app.ActualizarButton.Enable = "off";
                app.StatusLabel_A.Text = "Parámetros enviados exitosamente";

                pause(1);
                app.StatusLabel_A.Visible = "off";
                app.ActualizarButton.Enable = "on";

            catch ME
                % Manejo de errores y actualizar el estado en la GUI
                app.StatusLabel_A.Visible = "on";
                app.ActualizarButton.Enable = "off";
                app.StatusLabel_A.Text = "Error enviando parámetros";
                disp(ME.message);

                pause(1);
                app.StatusLabel_A.Visible = "off";
                app.ActualizarButton.Enable = "on";
            end
        end

        % Button pushed function: EscalonButton
        function escalonButton(app, event)
            app.setPoint = app.setPoint + 10;  %incremento del setpoint
            app.SetpointCEditField.Value = app.setPoint; % actualiza el setpoint en la GUI
            ActualizarButtonPushed(app, event); %envia data al micro

        end

        % Close request function: UIFigure
        function UI_close(app, event)
            clear app.serialConection

            app.EstadodeconexionLamp.Color    = [0.78, 0.1, 0.1]; %rojo
            app.EstadodelcalefactorLamp.Color = [0.78, 0.1, 0.1]; %rojo
            app.ActualizarButton.Enable       = "off";

            delete(app)
            
        end

        % Callback function
        function activarDarkMode(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UI_close, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'tiempo vs. temperatura')
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Temperatura [C°]')
            zlabel(app.UIAxes, 'Z')
            app.SetpointCLabel = uilabel(app.UIFigure);
            app.SetpointCLabel.Text = 'Set-point [°C]';
            app.SetpointCEditField = uieditfield(app.UIFigure, 'numeric');
            app.HisteresisCEditFieldLabel = uilabel(app.UIFigure);
            app.HisteresisCEditFieldLabel.Text = 'Histéeresis [°C]';
            app.HisteresisCEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervalodemuestreosEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalodemuestreosEditFieldLabel.Text = 'Intervalo de muestreo [s]';
            app.IntervalodemuestreosEditField = uieditfield(app.UIFigure, 'numeric');
            app.ActualizarButton = uibutton(app.UIFigure, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.EstadodelcalefactorLampLabel = uilabel(app.UIFigure);
            app.EstadodelcalefactorLampLabel.Text = 'Estado del calefactor';
            app.EstadodelcalefactorLamp = uilamp(app.UIFigure);
            app.EstadodeconexionLampLabel = uilabel(app.UIFigure);
            app.EstadodeconexionLampLabel.Text = 'Estado de conexion';
            app.EstadodeconexionLamp = uilamp(app.UIFigure);
            app.PORTDropDownLabel = uilabel(app.UIFigure);
            app.PORTDropDownLabel.Text = 'PORT';
            app.PORTDropDown = uidropdown(app.UIFigure);
            app.PORTDropDown.Items = {''};
            app.PORTDropDown.Value = '';
            app.BaudRateDropDownLabel = uilabel(app.UIFigure);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.UIFigure);
            app.BaudRateDropDown.Items = {''};
            app.BaudRateDropDown.Value = '';
            app.ConectarButton = uibutton(app.UIFigure, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.StatusLabel_A = uilabel(app.UIFigure);
            app.StatusLabel_A.Text = 'StatusLabel_A';
            app.TemperaturaGaugeLabel = uilabel(app.UIFigure);
            app.TemperaturaGaugeLabel.Text = 'Temperatura';
            app.TemperaturaGauge = uigauge(app.UIFigure, 'linear');
            app.EscalonButton = uibutton(app.UIFigure, 'push');
            app.EscalonButton.ButtonPushedFcn = createCallbackFcn(app, @escalonButton, true);
            app.EscalonButton.Text = 'Escalon';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controlTemperatura

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end